classdef loopShapingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoopShapingGUI       matlab.ui.Figure
        ControllerPanel      matlab.ui.container.Panel
        LeadLag              matlab.ui.control.Button
        Notch                matlab.ui.control.Button
        Gain                 matlab.ui.control.Button
        PI                   matlab.ui.control.Button
        PID                  matlab.ui.control.Button
        AddPolesZeros        matlab.ui.control.Button
        AutomaticDesigner    matlab.ui.control.Button
        ResetContoller       matlab.ui.control.Button
        ControllerText       matlab.ui.control.Label
        CLBode               matlab.ui.control.Button
        OLBode               matlab.ui.control.Button
        Status               matlab.ui.control.Label
        Nichols              matlab.ui.control.Button
        stable               matlab.ui.control.Label
        CurrentlyShowing     matlab.ui.control.Label
        CurrentPlot          matlab.ui.control.Label
        CurrentController    matlab.ui.control.Label
        NicholsBodePanel     matlab.ui.container.Panel
        nicholsPlot          matlab.ui.control.UIAxes
        ZPKMapPanel          matlab.ui.container.Panel
        zpkMap               matlab.ui.control.UIAxes
        zpkTable_p           matlab.ui.control.Table
        zpkTable_z           matlab.ui.control.Table
        OptionsPanel         matlab.ui.container.Panel
        SaveController       matlab.ui.control.Button
        SaveNicholsFig       matlab.ui.control.Button
        SaveZPKMapFig        matlab.ui.control.Button
        LoadController       matlab.ui.control.Button
        LoadPlant            matlab.ui.control.Button
        Discrete             matlab.ui.control.Button
        Help                 matlab.ui.control.Button
        LoopShapingGUILabel  matlab.ui.control.Label
        QFTLogo              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %print the controller on text
            G = evalin('base','G');
            Gtf = tf(G);
            syms s
            [num_P,den_P] = tfdata(Gtf);
            Gsym = vpa(poly2sym((num_P),s)/poly2sym((den_P),s),2);
            Gstr = print_sym_fraction(Gsym);
            app.CurrentController.Text = Gstr;
            
            %print pz map
            [p,z] = pzmap(G);
            plot(app.zpkMap,real(p),imag(p),'xr')
            hold(app.zpkMap,'on')
            plot(app.zpkMap,real(z),imag(z),'ob')
            hold(app.zpkMap,'on')
            plot(app.zpkMap,[min(real(p)) max(real(p))],[0 0],'k','LineWidth',0.5)
            hold(app.zpkMap,'on')
            plot(app.zpkMap,[0 0],[-30 30],'k','LineWidth',0.5)
            
            %fill pz table
            for i=1:length(p)
             sys_poles(i,:) = {'pole' real(p(i)) imag(p(i))}; %sample data for each column
            end
            for j=1:length(z)
             sys_zeros(j,:) = {'zero' real(z(j)) imag(z(j))}; %sample data for each column
            end
             app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles];
             app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
            
        end

        % Button pushed function: LoadPlant
        function LoadPlantButtonPushed(app, event)
            G = evalin('base','G');
            des = evalin('base','des');     
            h = des.showbnd('odsrs',[],[3 5 7 10 20 50]);
            des.showbnd('rsrs',h,[0.4 0.5 1]);
            des.loopnic(G)
            ngrid


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoopShapingGUI = uifigure('Visible', 'off');
            app.LoopShapingGUI.Name = 'UI Figure';
            app.ControllerPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPanel.Title = 'Controller';
            app.LeadLag = uibutton(app.ControllerPanel, 'push');
            app.LeadLag.Text = 'Lead/Lag';
            app.Notch = uibutton(app.ControllerPanel, 'push');
            app.Notch.Text = 'Notch/anti Notch';
            app.Gain = uibutton(app.ControllerPanel, 'push');
            app.Gain.Text = 'Gain';
            app.PI = uibutton(app.ControllerPanel, 'push');
            app.PI.Text = 'PI';
            app.PID = uibutton(app.ControllerPanel, 'push');
            app.PID.Text = 'PID';
            app.AddPolesZeros = uibutton(app.ControllerPanel, 'push');
            app.AddPolesZeros.Text = 'Add Poles/Zeros';
            app.AutomaticDesigner = uibutton(app.ControllerPanel, 'push');
            app.AutomaticDesigner.Text = 'Automatic Designer';
            app.ResetContoller = uibutton(app.ControllerPanel, 'push');
            app.ResetContoller.Text = 'Reset Contoller';
            app.ControllerText = uilabel(app.ControllerPanel);
            app.ControllerText.Text = 'Current Contoller:';
            app.CLBode = uibutton(app.ControllerPanel, 'push');
            app.CLBode.Text = 'Close Loop Bode';
            app.OLBode = uibutton(app.ControllerPanel, 'push');
            app.OLBode.Text = 'Open Loop Bode';
            app.Status = uilabel(app.ControllerPanel);
            app.Status.Text = 'Status:';
            app.Nichols = uibutton(app.ControllerPanel, 'push');
            app.Nichols.Text = 'Nichols';
            app.stable = uilabel(app.ControllerPanel);
            app.stable.Text = 'Unstable';
            app.CurrentlyShowing = uilabel(app.ControllerPanel);
            app.CurrentlyShowing.Text = 'Currently Showing:';
            app.CurrentPlot = uilabel(app.ControllerPanel);
            app.CurrentPlot.Text = 'Nichols';
            app.CurrentController = uilabel(app.ControllerPanel);
            app.CurrentController.Text = 'G(s) = 1';
            app.NicholsBodePanel = uipanel(app.LoopShapingGUI);
            app.NicholsBodePanel.Title = 'Nichols / Bode';
            app.nicholsPlot = uiaxes(app.NicholsBodePanel);
            title(app.nicholsPlot, 'Title')
            xlabel(app.nicholsPlot, 'X')
            ylabel(app.nicholsPlot, 'Y')
            app.ZPKMapPanel = uipanel(app.LoopShapingGUI);
            app.ZPKMapPanel.Title = 'ZPK Map';
            app.zpkMap = uiaxes(app.ZPKMapPanel);
            title(app.zpkMap, 'Title')
            xlabel(app.zpkMap, 'X')
            ylabel(app.zpkMap, 'Y')
            app.zpkTable_p = uitable(app.ZPKMapPanel);
            app.zpkTable_p.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_p.RowName = {''; ''; ''};
            app.zpkTable_z = uitable(app.ZPKMapPanel);
            app.zpkTable_z.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_z.RowName = {''; ''; ''};
            app.OptionsPanel = uipanel(app.LoopShapingGUI);
            app.OptionsPanel.Title = 'Options';
            app.SaveController = uibutton(app.OptionsPanel, 'push');
            app.SaveController.Text = 'Save Controller To Workspace';
            app.SaveNicholsFig = uibutton(app.OptionsPanel, 'push');
            app.SaveNicholsFig.Text = 'Save Nichols Figure';
            app.SaveZPKMapFig = uibutton(app.OptionsPanel, 'push');
            app.SaveZPKMapFig.Text = 'Save ZPK Map Figure';
            app.LoadController = uibutton(app.OptionsPanel, 'push');
            app.LoadController.Text = 'Load Controller From Workspace';
            app.LoadPlant = uibutton(app.OptionsPanel, 'push');
            app.LoadPlant.ButtonPushedFcn = createCallbackFcn(app, @LoadPlantButtonPushed, true);
            app.LoadPlant.Text = 'Load Plant From Workspace';
            app.Discrete = uibutton(app.OptionsPanel, 'push');
            app.Discrete.Text = 'Discrete Design';
            app.Help = uibutton(app.OptionsPanel, 'push');
            app.Help.Text = 'Help!';
            app.LoopShapingGUILabel = uilabel(app.LoopShapingGUI);
            app.LoopShapingGUILabel.Text = 'Loop Shaping GUI';
            app.QFTLogo = uiimage(app.LoopShapingGUI);
            app.QFTLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.LoopShapingGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loopShapingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoopShapingGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoopShapingGUI)
        end
    end
end